classdef eyeAlign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        STEP1Tab                      matlab.ui.container.Tab
        stepOcomp                     matlab.ui.control.Button
        keepTab                       matlab.ui.control.Table
        SessionCheckPanel             matlab.ui.container.Panel
        textINFO                      matlab.ui.control.Label
        recDUR                        matlab.ui.control.NumericEditField
        TotalnumberofEventsLabel      matlab.ui.control.Label
        curSESS                       matlab.ui.control.Spinner
        SessionSpinnerLabel           matlab.ui.control.Label
        selectSess                    matlab.ui.container.ButtonGroup
        KeepButton                    matlab.ui.control.RadioButton
        IgnoreButton                  matlab.ui.control.RadioButton
        stopIND                       matlab.ui.control.NumericEditField
        StopEventIndexLabel           matlab.ui.control.Label
        startIND                      matlab.ui.control.NumericEditField
        StartEventIndexLabel          matlab.ui.control.Label
        STEP2Tab                      matlab.ui.container.Tab
        stepTwcomp                    matlab.ui.control.Button
        ACCEPTButton                  matlab.ui.control.Button
        SessionCUR                    matlab.ui.control.EditField
        SESSIONEditFieldLabel         matlab.ui.control.Label
        StopINDU                      matlab.ui.control.NumericEditField
        STOPIndexEditFieldLabel       matlab.ui.control.Label
        StartINDU                     matlab.ui.control.NumericEditField
        STARTIndexEditFieldLabel      matlab.ui.control.Label
        STOPIndicesPanel              matlab.ui.container.Panel
        stopTAB                       matlab.ui.control.Table
        STARTIndicesPanel             matlab.ui.container.Panel
        startTAB                      matlab.ui.control.Table
        STEP3Tab                      matlab.ui.container.Tab
        FinalUCHProcessingPanel       matlab.ui.container.Panel
        uchEYE                        matlab.ui.control.Button
        stepThcomp                    matlab.ui.control.Button
        SAVEButton                    matlab.ui.control.Button
        InitialEyeProcessingPanel     matlab.ui.container.Panel
        conBehD                       matlab.ui.control.Button
        BehCONpan                     matlab.ui.container.Panel
        minUts                        matlab.ui.control.Spinner
        MinuteLabel                   matlab.ui.control.Label
        CheckButton                   matlab.ui.control.Button
        behdateFin                    matlab.ui.control.EditField
        FinalBehDateEditFieldLabel    matlab.ui.control.Label
        behdateOrg                    matlab.ui.control.EditField
        BehavioralDateEditFieldLabel  matlab.ui.control.Label
        acceptBEH                     matlab.ui.control.Button
        yearS                         matlab.ui.control.Spinner
        YearSpinnerLabel              matlab.ui.control.Label
        hourS                         matlab.ui.control.Spinner
        HourSpinnerLabel              matlab.ui.control.Label
        dayS                          matlab.ui.control.Spinner
        DaySpinnerLabel               matlab.ui.control.Label
        monthS                        matlab.ui.control.Spinner
        MonthSpinnerLabel             matlab.ui.control.Label
        eyePROC                       matlab.ui.control.Button
        step3_dirs                    matlab.ui.container.Panel
        reset_PE                      matlab.ui.control.Button
        reset_RE                      matlab.ui.control.Button
        reset_RB                      matlab.ui.control.Button
        proE_loc                      matlab.ui.control.EditField
        ProcEyeEditFieldLabel         matlab.ui.control.Label
        rawE_loc                      matlab.ui.control.EditField
        RawEyeEditFieldLabel          matlab.ui.control.Label
        rawB_loc                      matlab.ui.control.EditField
        RawBehaviorEditFieldLabel     matlab.ui.control.Label
        STEP4Tab                      matlab.ui.container.Tab
        Panel7                        matlab.ui.container.Panel
        Panel6                        matlab.ui.container.Panel
        DONEButton                    matlab.ui.control.Button
        ACCEPTButton_2                matlab.ui.control.Button
        Panel5                        matlab.ui.container.Panel
        finSessCk                     matlab.ui.control.Table
        NLXEyeCheckPanel              matlab.ui.container.Panel
        Label_2                       matlab.ui.control.Label
        SETmainLoc                    matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        STARTButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        tmpDir % Description
        saveSall
        keepLOG
        stepAlltab % Description
        stepTwLOG % Description
        MainLOC % Description
        dataLOCS
        sessEyBhNlxTAB
        BehTrack
        behdateLog
        sessEdata
    end
    
    methods (Access = private)
        
        function updateValues(app)

            useSess = app.curSESS.Value;

            app.startIND.Value = app.saveSall{useSess,1}.StartIndex;
            app.stopIND.Value = app.saveSall{useSess,1}.StopIndex;
            app.recDUR.Value = app.stopIND.Value - app.startIND.Value;
            
        end
        
        function updateRowHue(app)

            ignStyle = uistyle('BackgroundColor',[1 0.05 0.05]);
            kepStyle = uistyle('BackgroundColor',[0.05 1 0.05]);
            addStyle(app.keepTab,ignStyle,'row',find(~app.keepLOG))
            addStyle(app.keepTab,kepStyle,'row',find(app.keepLOG))
            
        end
        
        function output = getTextInfo(~,input)

            slice1 = split(input,' ');
            % slice2 = slice1(7);
            % slice3 = split(slice2,'_');
            dAY = slice1{2};
            hOUR = slice1{3};
            timeColl = [dAY ' ' hOUR];
            output = datetime(timeColl, 'InputFormat', 'yyyy/MM/dd HH:mm:SS');

        end
        
        function updateINDXsel(app)

            % Get current index STEP 2
            useSess = app.stepAlltab.SessNum(app.stepTwLOG);

            sessTTLinfo = app.saveSall{useSess,1}.SessionInfo.tInfo;
            sessTTLinfoT = trimString(app,sessTTLinfo);

             % update start index table
            startINDt = app.saveSall{useSess,1}.StartIndex;

            tmpSTARTit = startINDt-4;
            tmpSTARTet = startINDt+4;
            if tmpSTARTit < 1
                tmpSTARTit = 1;
            end

            startBlock = tmpSTARTit:tmpSTARTet;
            startEVENTS = sessTTLinfoT(startBlock);
            startTABtmp = table(startEVENTS,transpose(startBlock),...
                'VariableNames',{'Events','RowN'});
            app.startTAB.Data = startTABtmp;

            % update stop index table
            stopINDt = app.saveSall{useSess,1}.StopIndex;
            tmpSTOPit = stopINDt-4;
            tmpSTOPet = stopINDt+4;
            if tmpSTOPet > length(sessTTLinfoT)
                tmpSTOPet = length(sessTTLinfoT);
            end

            stopBlock = tmpSTOPit:tmpSTOPet;
            stopEVENTS = sessTTLinfoT(stopBlock);
            stopTABtmp = table(stopEVENTS,transpose(stopBlock),...
                'VariableNames',{'Events','RowN'});
            app.stopTAB.Data = stopTABtmp;

            app.SessionCUR.Value = num2str(useSess);

        end
        
        function outTTLi = trimString(~,inTTLi)

            recRows = contains(inTTLi,'Recording');
            trimmS = cellfun(@(x) x(40:end), inTTLi(~recRows), 'UniformOutput', false);
            inTTLi(~recRows) = trimmS;
            outTTLi = inTTLi;

        end
        
        function flist = getFlist(~, dirIN, type)

            cd(dirIN)

            switch type
                case 1
                    flin1 = dir('*.txt');
                    flin2 = {flin1.name};
                case 2
                    flin1 = dir('*.edf');
                    flin2 = {flin1.name};
                case 3
                    flin0 = dir();
                    flinNs = {flin0.name};
                    flin01 = [flin0.isdir];
                    flin1 = flinNs(flin01);
                    flin2 = flin1(~ismember(flin1,{'.','..'}));
            end
            
            flist = flin2;

        end
        
        function updateBehCheck(app)

            yEAR = num2str(app.yearS.Value);
            mONTH = num2str(app.monthS.Value);
            daY = num2str(app.dayS.Value);
            hOUr2 = num2str(app.hourS.Value);
            minUTE = num2str(app.minUts.Value);

            if length(mONTH) == 1
                monTu = 'M';
            else
                monTu = 'MM';
            end
            if length(daY) == 1
                daTu = 'd';
            else
                daTu = 'dd';
            end
            if length(minUTE) == 1
                minTu = 'm';
            else
                minTu = 'mm';
            end

            tmpbeD = datetime([yEAR, '-' mONTH, '-', daY, '-' , hOUr2,'-',minUTE],...
                'InputFormat',...
                ['yyyy-',monTu,'-',daTu,'-HH-',minTu]);

            app.behdateFin.Value = char(tmpbeD);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            
            directory = 'Y:\';
            patDir = 'PatientData_MW\MW9\NWBProcessing\';
            app.tmpDir = [directory , patDir];

            sessDir = [app.tmpDir , 'Session_Data\'];
            cd(sessDir)
            load('allSessionData.mat','saveSessAll')
            
            sessAAL = transpose(1:length(saveSessAll));

            app.keepLOG = zeros(length(sessAAL),1,'logical');

            app.keepTab.Data(:,1) = sessAAL;
            app.keepTab.Data(:,2) = app.keepLOG;
            ignStyle = uistyle('BackgroundColor',[1 0.1 0.1]);
            addStyle(app.keepTab,ignStyle,'row',1:15)

            app.curSESS.Value = sessAAL(1);

            app.saveSall = saveSessAll;

            app.startIND.Value = saveSessAll{1,1}.StartIndex;
            app.stopIND.Value = saveSessAll{1,1}.StopIndex;
            app.recDUR.Value = app.stopIND.Value - app.startIND.Value;

            app.curSESS.Limits = [1 length(app.saveSall)];

            app.selectSess.Enable = 'on';
            app.SessionCheckPanel.Enable = 'on';
            app.stepOcomp.Enable = "on";


        end

        % Value changed function: curSESS
        function curSESSValueChanged(app, event)

            updateValues(app)
            if app.keepLOG(app.curSESS.Value)
                app.KeepButton.Value = 1;
                app.IgnoreButton.Value = 0;
            else
                app.KeepButton.Value = 0;
                app.IgnoreButton.Value = 1;
            end
            
        end

        % Selection changed function: selectSess
        function selectSessSelectionChanged(app, event)
            selBu = app.selectSess.SelectedObject;

            if matches(selBu.Text,'Keep')
                app.keepLOG(app.curSESS.Value) = true;
            elseif matches(selBu.Text,'Ignore')
                app.keepLOG(app.curSESS.Value) = false;
            end

            app.keepTab.Data(:,2) = app.keepLOG;

            updateRowHue(app)

        end

        % Button pushed function: stepOcomp
        function stepOcompButtonPushed(app, event)
            
            app.SessionCheckPanel.Enable = 'off';

            durAll = zeros(sum(app.keepLOG),1);
            dateAll = cell(sum(app.keepLOG),1);
            sessAAL = find(app.keepLOG);

            % Create table
            for si = 1:sum(app.keepLOG)
                tmpSesU = sessAAL(si);
                tmpStart = app.saveSall{tmpSesU,1}.StartIndex;
                tmpStop = app.saveSall{tmpSesU,1}.StopIndex;
                durAll(si) = tmpStop - tmpStart;
                tmpStrg = app.saveSall{tmpSesU,1}.SessionInfo.header(7);
                dateAll{si} = getTextInfo(app,tmpStrg);
            end

            app.stepAlltab = table(sessAAL, durAll, dateAll,'VariableNames',{'SessNum',...
                'Duration','Date'});

            app.stepTwLOG = 1;

            updateINDXsel(app)

            app.stepTwcomp.Enable = "on";

        end

        % Button pushed function: ACCEPTButton
        function ACCEPTButtonPushed(app, event)
            % Clean up STYLES %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.stepAlltab.StartIND(app.stepTwLOG) = app.StartINDU.Value;
            app.stepAlltab.StopIND(app.stepTwLOG) = app.StopINDU.Value;

            app.stepTwLOG = app.stepTwLOG + 1;
            app.StartINDU.Value = 0;
            app.StopINDU.Value = 0;

            % Check if the end or not
            if app.stepTwLOG > height(app.stepAlltab)
                app.stepTwLOG = height(app.stepAlltab);
                app.ACCEPTButton.Enable = "off";
                return
            else
                updateINDXsel(app)
            end

        end

        % Button pushed function: stepTwcomp
        function stepTwcompButtonPushed(app, event)
            

            app.ACCEPTButton.Enable = "off";

            app.SETmainLoc.Enable = "on";


        end

        % Button pushed function: SETmainLoc
        function SETmainLocButtonPushed(app, event)
            

            % Locate main folder and add folders
            app.MainLOC = uigetdir();

            cd(app.MainLOC)
            foldLIST = getFlist(app,app.MainLOC,3);

            EyeCheck = sum(cellfun(@(x) sum(matches(x,{...
                'Eye-tracking'})),foldLIST));

            eyeRaw = [app.MainLOC, filesep, 'Eye-tracking', filesep, 'Raw'];
            eyeProc = [app.MainLOC, filesep, 'Eye-tracking', filesep, 'Processed'];

            if EyeCheck && exist(eyeRaw,'dir')
                app.rawE_loc.Value = eyeRaw;
                app.rawE_loc.BackgroundColor = [0.1 1 0.1];
            else
                app.rawE_loc.BackgroundColor = [1 0.1 0.1];
                app.reset_RE.Enable = "on";
                return
            end

            if EyeCheck && exist(eyeProc,'dir')
                app.proE_loc.Value = eyeProc;
                app.proE_loc.BackgroundColor = [0.1 1 0.1];
            else
                app.proE_loc.BackgroundColor = [1 0.1 0.1];
                app.reset_PE.Enable = "on";
                return
            end

            BehCheck = sum(cellfun(@(x) sum(matches(x,{'Behavioral-Data',...
                })),foldLIST));

            behRaw = [app.MainLOC, filesep, 'Behavioral-Data', filesep, 'Raw'];

            if BehCheck && exist(behRaw,'dir')
                app.rawB_loc.Value = behRaw;
                app.rawB_loc.BackgroundColor = [0.1 1 0.1];
            else
                app.rawB_loc.BackgroundColor = [1 0.1 0.1];
                app.reset_RB.Enable = "on";
                return
            end

            app.dataLOCS.EyeR = eyeRaw;
            app.dataLOCS.EyeP = eyeProc;
            app.dataLOCS.BehR = behRaw;
            
            app.eyePROC.Enable = "on";
            app.step3_dirs.Enable = "on";

        end

        % Button pushed function: eyePROC
        function eyePROCButtonPushed(app, event)
            

            [txtList] = getFlist(app,app.dataLOCS.BehR, 1);
            [eyeList] = getFlist(app,app.dataLOCS.EyeR, 2);
            dateID = cell(length(txtList),1);
            blockt = cell(length(txtList),1);
            varT = cell(length(txtList),1);
            texTfn = cell(length(txtList),1);
            eyefn = cell(length(txtList),1);
            eyePfn = cell(length(txtList),1);

            for bi = 1:length(txtList)
                cd(app.dataLOCS.BehR)
                tmpTxt = txtList{bi};
                tmpSp = split(tmpTxt,'.');
                tmpRm = extractAfter(tmpSp,'NEWOLDDELAY5');
                tmpRm2 = tmpRm{cellfun(@(x) ~isempty(x), tmpRm)};
                dateID{bi} = tmpRm2;

                % get block id
                txtTAB = readtable(tmpTxt);
                txtLINE = table2cell(txtTAB(1,3));
                % first is variant [1, 2, 3]
                % second is block [1 = learn, 2 = recog]
                txtSp = split(txtLINE,',');
                if matches(txtSp{2},'1')
                    blockt{bi} = 'Learn';
                elseif matches(txtSp{2},'2')
                    blockt{bi} = 'Recog';
                end

                varT{bi} = ['Var_', txtSp{1}];

                % find EDF file
                cd(app.dataLOCS.EyeR)
                eyeSp = split(eyeList,'.');
                eyeSpA = eyeSp(:,:,1);
                eyeRM = extractAfter(eyeSpA,'NO');

                eyeFind = eyeList{matches(eyeRM,tmpRm2)};
                texTfn{bi} = tmpTxt;
                eyefn{bi} = eyeFind;

                eyePrName = Edf2Mat_UCH(eyeFind, 'MW9', blockt{bi}(1), txtSp{1},...
                    app.dataLOCS.EyeR, app.dataLOCS.EyeP);

                eyePfn{bi} = eyePrName;
            end

            sessEBtable = table(dateID, blockt , varT , texTfn , eyefn , eyePfn,...
                'VariableNames', {'FDate','Block','Variant','BehTxt','EyeTxtR','EyeTxtP'});

            % Combine tables
            app.sessEyBhNlxTAB = [sessEBtable , app.stepAlltab];

            app.eyePROC.Enable = "off";
            app.conBehD.Enable = "on";


        end

        % Button pushed function: conBehD
        function conBehDButtonPushed(app, event)
            app.BehCONpan.Visible = "on";

            app.BehTrack = 1;
            tmpT = app.sessEyBhNlxTAB.FDate{app.BehTrack};
            app.behdateOrg.Value = tmpT;
            app.yearS.Value = 2022;
            app.dayS.Value = 1;
            app.monthS.Value = 1;
            app.hourS.Value = 0;
            app.minUts.Value = 0;

            updateBehCheck(app)


        end

        % Button pushed function: acceptBEH
        function acceptBEHButtonPushed(app, event)
            
            app.behdateLog{app.BehTrack} = app.behdateFin.Value;
            if app.BehTrack == height(app.sessEyBhNlxTAB)
                app.BehTrack = height(app.sessEyBhNlxTAB);
                app.BehCONpan.Visible = "off";
                app.sessEyBhNlxTAB.BehEdate = transpose(app.behdateLog);
                app.uchEYE.Enable = "on";
                return
            else
                app.BehTrack = app.BehTrack + 1;
                tmpT = app.sessEyBhNlxTAB.FDate{app.BehTrack};
                app.behdateOrg.Value = tmpT;
                updateBehCheck(app)
            end

        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
             updateBehCheck(app);
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            
            sessEBNtable = app.sessEyBhNlxTAB;
            sessEyedata = app.sessEdata;

            % CD to Save location
            cd([app.MainLOC , filesep, 'NWBProcessing\Aggregate_Data'])
            save('SessionEyeNLX.mat','sessEBNtable','sessEyedata');

            app.stepThcomp.Enable = "on";


        end

        % Button pushed function: uchEYE
        function uchEYEButtonPushed(app, event)
           
            app.sessEdata = struct;
            for eii = 1:height(app.sessEyBhNlxTAB)

                tmpSessTAB = app.sessEyBhNlxTAB.SessNum(eii);

                [tsTable, ~, ~, ~, rawTab] = ExtractEyeInfo_v2(app.sessEyBhNlxTAB.EyeTxtP{eii});
                app.sessEdata.(['S_',num2str(tmpSessTAB)]).TStable = tsTable;
                app.sessEdata.(['S_',num2str(tmpSessTAB)]).RAWtable = rawTab;

            end

            app.SAVEButton.Enable = 'on';


        end

        % Button pushed function: stepThcomp
        function stepThcompButtonPushed(app, event)
            app.SAVEButton.Enable = "off";
            app.uchEYE.Enable = "off";

            app.finSessCk.Data = app.sessEyBhNlxTAB;

        end

        % Value changed function: StartINDU
        function StartINDUValueChanged(app, event)
            value = app.StartINDU.Value;
            rowIDi = find(table2array(app.startTAB.Data(:,2)) == value);
            rowIDo = find(table2array(app.startTAB.Data(:,2)) ~= value);
            startStyle = uistyle('BackgroundColor',[0.1 1 0.1]);
            restStyle = uistyle('BackgroundColor',[0.9412 0.9412 0.9412]);
            addStyle(app.startTAB,startStyle,'row',rowIDi)
            addStyle(app.startTAB,restStyle,'row',rowIDo)

        end

        % Value changed function: StopINDU
        function StopINDUValueChanged(app, event)
            value = app.StopINDU.Value;
            rowIDi = find(table2array(app.stopTAB.Data(:,2)) == value);
            rowIDo = find(table2array(app.stopTAB.Data(:,2)) ~= value);
            stopStyle = uistyle('BackgroundColor',[1 0.1 0.1]);
            restStyle = uistyle('BackgroundColor',[0.9412 0.9412 0.9412]);
            addStyle(app.stopTAB,stopStyle,'row',rowIDi)
            addStyle(app.stopTAB,restStyle,'row',rowIDo)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NLXEyeCheckPanel = uipanel(app.UIFigure);
            app.NLXEyeCheckPanel.Title = 'NLX - Eye - Check ';
            app.STARTButton = uibutton(app.NLXEyeCheckPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.Label = uilabel(app.NLXEyeCheckPanel);
            app.Label.Text = '|';
            app.SETmainLoc = uibutton(app.NLXEyeCheckPanel, 'push');
            app.SETmainLoc.ButtonPushedFcn = createCallbackFcn(app, @SETmainLocButtonPushed, true);
            app.SETmainLoc.Enable = 'off';
            app.SETmainLoc.Text = 'SET Main LOC';
            app.Label_2 = uilabel(app.NLXEyeCheckPanel);
            app.Label_2.Text = '|';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.STEP1Tab = uitab(app.TabGroup);
            app.STEP1Tab.Title = 'STEP 1';
            app.SessionCheckPanel = uipanel(app.STEP1Tab);
            app.SessionCheckPanel.Enable = 'off';
            app.SessionCheckPanel.Title = 'Session Check';
            app.StartEventIndexLabel = uilabel(app.SessionCheckPanel);
            app.StartEventIndexLabel.Text = 'Start Event Index';
            app.startIND = uieditfield(app.SessionCheckPanel, 'numeric');
            app.StopEventIndexLabel = uilabel(app.SessionCheckPanel);
            app.StopEventIndexLabel.Text = 'Stop Event Index';
            app.stopIND = uieditfield(app.SessionCheckPanel, 'numeric');
            app.selectSess = uibuttongroup(app.SessionCheckPanel);
            app.selectSess.SelectionChangedFcn = createCallbackFcn(app, @selectSessSelectionChanged, true);
            app.selectSess.Enable = 'off';
            app.selectSess.Title = 'Session Select';
            app.IgnoreButton = uiradiobutton(app.selectSess);
            app.IgnoreButton.Text = 'Ignore';
            app.IgnoreButton.Value = true;
            app.KeepButton = uiradiobutton(app.selectSess);
            app.KeepButton.Text = 'Keep';
            app.SessionSpinnerLabel = uilabel(app.SessionCheckPanel);
            app.SessionSpinnerLabel.Text = 'Session';
            app.curSESS = uispinner(app.SessionCheckPanel);
            app.curSESS.Limits = [1 30];
            app.curSESS.ValueChangedFcn = createCallbackFcn(app, @curSESSValueChanged, true);
            app.curSESS.Value = 1;
            app.TotalnumberofEventsLabel = uilabel(app.SessionCheckPanel);
            app.TotalnumberofEventsLabel.Text = 'Total number of Events';
            app.recDUR = uieditfield(app.SessionCheckPanel, 'numeric');
            app.textINFO = uilabel(app.SessionCheckPanel);
            app.textINFO.Text = '*Identify Sessions with ~500 or ~1000 EVENTS';
            app.keepTab = uitable(app.STEP1Tab);
            app.keepTab.ColumnName = {'Session'; 'KEEP'};
            app.keepTab.RowName = {};
            app.stepOcomp = uibutton(app.STEP1Tab, 'push');
            app.stepOcomp.ButtonPushedFcn = createCallbackFcn(app, @stepOcompButtonPushed, true);
            app.stepOcomp.Enable = 'off';
            app.stepOcomp.Text = {'STEP 1'; 'COMPLETE'};
            app.STEP2Tab = uitab(app.TabGroup);
            app.STEP2Tab.Title = 'STEP 2';
            app.STARTIndicesPanel = uipanel(app.STEP2Tab);
            app.STARTIndicesPanel.Title = 'START Indices';
            app.startTAB = uitable(app.STARTIndicesPanel);
            app.startTAB.ColumnName = {'Event Name'; 'Row#'};
            app.startTAB.RowName = {};
            app.STOPIndicesPanel = uipanel(app.STEP2Tab);
            app.STOPIndicesPanel.Title = 'STOP Indices';
            app.stopTAB = uitable(app.STOPIndicesPanel);
            app.stopTAB.ColumnName = {'Event Name'; 'Row#'};
            app.stopTAB.RowName = {};
            app.STARTIndexEditFieldLabel = uilabel(app.STEP2Tab);
            app.STARTIndexEditFieldLabel.Text = 'START Index';
            app.StartINDU = uieditfield(app.STEP2Tab, 'numeric');
            app.StartINDU.ValueChangedFcn = createCallbackFcn(app, @StartINDUValueChanged, true);
            app.STOPIndexEditFieldLabel = uilabel(app.STEP2Tab);
            app.STOPIndexEditFieldLabel.Text = 'STOP Index';
            app.StopINDU = uieditfield(app.STEP2Tab, 'numeric');
            app.StopINDU.ValueChangedFcn = createCallbackFcn(app, @StopINDUValueChanged, true);
            app.SESSIONEditFieldLabel = uilabel(app.STEP2Tab);
            app.SESSIONEditFieldLabel.Text = 'SESSION#';
            app.SessionCUR = uieditfield(app.STEP2Tab, 'text');
            app.ACCEPTButton = uibutton(app.STEP2Tab, 'push');
            app.ACCEPTButton.ButtonPushedFcn = createCallbackFcn(app, @ACCEPTButtonPushed, true);
            app.ACCEPTButton.Text = 'ACCEPT';
            app.stepTwcomp = uibutton(app.STEP2Tab, 'push');
            app.stepTwcomp.ButtonPushedFcn = createCallbackFcn(app, @stepTwcompButtonPushed, true);
            app.stepTwcomp.Enable = 'off';
            app.stepTwcomp.Text = {'STEP 2'; 'COMPLETE'};
            app.STEP3Tab = uitab(app.TabGroup);
            app.STEP3Tab.Title = 'STEP 3';
            app.step3_dirs = uipanel(app.STEP3Tab);
            app.step3_dirs.Enable = 'off';
            app.step3_dirs.Title = 'Directory Check';
            app.RawBehaviorEditFieldLabel = uilabel(app.step3_dirs);
            app.RawBehaviorEditFieldLabel.Text = 'Raw Behavior';
            app.rawB_loc = uieditfield(app.step3_dirs, 'text');
            app.rawB_loc.Value = '\MW#\Behavioral-Data\RawData';
            app.RawEyeEditFieldLabel = uilabel(app.step3_dirs);
            app.RawEyeEditFieldLabel.Text = 'Raw Eye';
            app.rawE_loc = uieditfield(app.step3_dirs, 'text');
            app.rawE_loc.Value = '\MW#\Eye-tracking\Raw';
            app.ProcEyeEditFieldLabel = uilabel(app.step3_dirs);
            app.ProcEyeEditFieldLabel.Text = 'Proc Eye';
            app.proE_loc = uieditfield(app.step3_dirs, 'text');
            app.proE_loc.Value = '\MW#\Eye-tracking\Processed';
            app.reset_RB = uibutton(app.step3_dirs, 'push');
            app.reset_RB.Enable = 'off';
            app.reset_RB.Text = 'RESET';
            app.reset_RE = uibutton(app.step3_dirs, 'push');
            app.reset_RE.Enable = 'off';
            app.reset_RE.Text = 'RESET';
            app.reset_PE = uibutton(app.step3_dirs, 'push');
            app.reset_PE.Enable = 'off';
            app.reset_PE.Text = 'RESET';
            app.InitialEyeProcessingPanel = uipanel(app.STEP3Tab);
            app.InitialEyeProcessingPanel.Title = 'Initial Eye Processing';
            app.eyePROC = uibutton(app.InitialEyeProcessingPanel, 'push');
            app.eyePROC.ButtonPushedFcn = createCallbackFcn(app, @eyePROCButtonPushed, true);
            app.eyePROC.Enable = 'off';
            app.eyePROC.Text = 'Run Eye Processing';
            app.BehCONpan = uipanel(app.InitialEyeProcessingPanel);
            app.BehCONpan.Title = 'Convet Behavior Text Time';
            app.BehCONpan.Visible = 'off';
            app.MonthSpinnerLabel = uilabel(app.BehCONpan);
            app.MonthSpinnerLabel.Text = 'Month';
            app.monthS = uispinner(app.BehCONpan);
            app.monthS.Limits = [1 12];
            app.monthS.Value = 1;
            app.DaySpinnerLabel = uilabel(app.BehCONpan);
            app.DaySpinnerLabel.Text = 'Day';
            app.dayS = uispinner(app.BehCONpan);
            app.dayS.Limits = [1 31];
            app.dayS.Value = 1;
            app.HourSpinnerLabel = uilabel(app.BehCONpan);
            app.HourSpinnerLabel.Text = 'Hour';
            app.hourS = uispinner(app.BehCONpan);
            app.hourS.Limits = [0 23];
            app.YearSpinnerLabel = uilabel(app.BehCONpan);
            app.YearSpinnerLabel.Text = 'Year';
            app.yearS = uispinner(app.BehCONpan);
            app.yearS.Limits = [2020 2030];
            app.yearS.Value = 2022;
            app.acceptBEH = uibutton(app.BehCONpan, 'push');
            app.acceptBEH.ButtonPushedFcn = createCallbackFcn(app, @acceptBEHButtonPushed, true);
            app.acceptBEH.Text = 'ACCEPT';
            app.BehavioralDateEditFieldLabel = uilabel(app.BehCONpan);
            app.BehavioralDateEditFieldLabel.Text = 'Behavioral Date';
            app.behdateOrg = uieditfield(app.BehCONpan, 'text');
            app.behdateOrg.Editable = 'off';
            app.FinalBehDateEditFieldLabel = uilabel(app.BehCONpan);
            app.FinalBehDateEditFieldLabel.Text = 'Final Beh Date';
            app.behdateFin = uieditfield(app.BehCONpan, 'text');
            app.behdateFin.Editable = 'off';
            app.CheckButton = uibutton(app.BehCONpan, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.MinuteLabel = uilabel(app.BehCONpan);
            app.MinuteLabel.Text = 'Minute';
            app.minUts = uispinner(app.BehCONpan);
            app.minUts.Limits = [0 59];
            app.conBehD = uibutton(app.InitialEyeProcessingPanel, 'push');
            app.conBehD.ButtonPushedFcn = createCallbackFcn(app, @conBehDButtonPushed, true);
            app.conBehD.Enable = 'off';
            app.conBehD.Text = 'Convert Beh Dates';
            app.FinalUCHProcessingPanel = uipanel(app.STEP3Tab);
            app.FinalUCHProcessingPanel.Title = 'Final UCH Processing';
            app.SAVEButton = uibutton(app.FinalUCHProcessingPanel, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Enable = 'off';
            app.SAVEButton.Text = 'SAVE';
            app.stepThcomp = uibutton(app.FinalUCHProcessingPanel, 'push');
            app.stepThcomp.ButtonPushedFcn = createCallbackFcn(app, @stepThcompButtonPushed, true);
            app.stepThcomp.Enable = 'off';
            app.stepThcomp.Text = {'STEP 3'; 'COMPLETE'};
            app.uchEYE = uibutton(app.FinalUCHProcessingPanel, 'push');
            app.uchEYE.ButtonPushedFcn = createCallbackFcn(app, @uchEYEButtonPushed, true);
            app.uchEYE.Enable = 'off';
            app.uchEYE.Text = 'UCH EYE Process';
            app.STEP4Tab = uitab(app.TabGroup);
            app.STEP4Tab.Title = 'STEP4';
            app.Panel5 = uipanel(app.STEP4Tab);
            app.Panel5.Title = 'Panel5';
            app.finSessCk = uitable(app.Panel5);
            app.finSessCk.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.finSessCk.RowName = {};
            app.Panel6 = uipanel(app.STEP4Tab);
            app.Panel6.Title = 'Panel6';
            app.ACCEPTButton_2 = uibutton(app.Panel6, 'push');
            app.ACCEPTButton_2.Text = 'ACCEPT';
            app.DONEButton = uibutton(app.Panel6, 'push');
            app.DONEButton.Text = 'DONE';
            app.Panel7 = uipanel(app.STEP4Tab);
            app.Panel7.Title = 'Panel7';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eyeAlign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end